classdef Calculadora < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModeladoComputacionalMIA2022UIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LinearSystemsTab                matlab.ui.container.Tab
        MatrizdecoeficientesATextAreaLabel  matlab.ui.control.Label
        CoeffMatrixArea                 matlab.ui.control.TextArea
        IncgnitasxTextAreaLabel         matlab.ui.control.Label
        xVectorArea                     matlab.ui.control.TextArea
        EqualLabel                      matlab.ui.control.Label
        VectorbLabel                    matlab.ui.control.Label
        bVectorArea                     matlab.ui.control.TextArea
        MtodoDropDownLabel              matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        NmerodeecuacionesEditFieldLabel  matlab.ui.control.Label
        AmountEquationsEditField        matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel        matlab.ui.control.Label
        NSToleranceEditField            matlab.ui.control.NumericEditField
        ErrordeaproximacinEditFieldLabel  matlab.ui.control.Label
        NSErrorEditField                matlab.ui.control.NumericEditField
        NSSolveButton                   matlab.ui.control.Button
        IteracionesmximasSpinnerLabel   matlab.ui.control.Label
        NSMaxIterationsSpinner          matlab.ui.control.Spinner
        NonLinearEquationsTab           matlab.ui.container.Tab
        NLEUIAxes                       matlab.ui.control.UIAxes
        EcuacinEditFieldLabel           matlab.ui.control.Label
        EquationEditField               matlab.ui.control.EditField
        PosicinaLabel                   matlab.ui.control.Label
        InitIntervalSpinner             matlab.ui.control.Spinner
        MtodoDropDownLabel_2            matlab.ui.control.Label
        MethodNLEDropDown               matlab.ui.control.DropDown
        PosicionbLabel                  matlab.ui.control.Label
        FinishIntervalSpinner           matlab.ui.control.Spinner
        ToleranciaEditFieldLabel_2      matlab.ui.control.Label
        NLEToleranceEditField           matlab.ui.control.NumericEditField
        ErrordeaproximacinEditFieldLabel_2  matlab.ui.control.Label
        NLEErrorEditField               matlab.ui.control.NumericEditField
        IteracionesmximasSpinnerLabel_2  matlab.ui.control.Label
        NLEMaxIterationsSpinner         matlab.ui.control.Spinner
        IteracionesEditFieldLabel       matlab.ui.control.Label
        NLEIterEditField                matlab.ui.control.NumericEditField
        RazencontradaEditFieldLabel     matlab.ui.control.Label
        NLERootEditField                matlab.ui.control.NumericEditField
        NLESolveButton                  matlab.ui.control.Button
        DiffEquationTab                 matlab.ui.container.Tab
        EcuacinEditFieldLabel_2         matlab.ui.control.Label
        DEEquationEditField             matlab.ui.control.EditField
        MtodoDropDownLabel_3            matlab.ui.control.Label
        DEMethodDropDown                matlab.ui.control.DropDown
        Posicininicialx0EditFieldLabel  matlab.ui.control.Label
        DEInitPosEditField              matlab.ui.control.NumericEditField
        Valorinicialy0EditFieldLabel    matlab.ui.control.Label
        DEInitValueEditField            matlab.ui.control.NumericEditField
        PosicinfinalxfEditFieldLabel    matlab.ui.control.Label
        DEFinalPosEditField             matlab.ui.control.NumericEditField
        ValorexactoyEditFieldLabel      matlab.ui.control.Label
        DEExactValueEditField           matlab.ui.control.NumericEditField
        CantidaddepasosEditFieldLabel   matlab.ui.control.Label
        DEStepsEditField                matlab.ui.control.NumericEditField
        VariacindepasosEditFieldLabel   matlab.ui.control.Label
        DEVarStepsEditField             matlab.ui.control.NumericEditField
        ErrorLabel                      matlab.ui.control.Label
        DEErrorEditField                matlab.ui.control.NumericEditField
        ValoraproximadoEditFieldLabel   matlab.ui.control.Label
        DEResultEditField               matlab.ui.control.NumericEditField
        DESolveButton                   matlab.ui.control.Button
        LoggerTextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        LinearSystemsInstance = LinearSystems();
        NonLinEqInstance = NonLinearEquations();
        DiffEqInstance = DifferentialEquations();
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            clc, value = app.MethodDropDown.Value;
            
            app.LinearSystemsInstance.setMethod(value);
            app.LoggerTextArea.Value = sprintf('Método seleccionado >> %s\n',value);
            
            if strcmp(value,'Gauss-Seidel') || ...
                    strcmp(value,'Sobre-Relajación Sucesiva') || ...
                    strcmp(value,'Gradiente Conjugado')
                
                app.NSErrorEditField.Enable = true;
                app.NSMaxIterationsSpinner.Enable = true;
                app.NSToleranceEditField.Enable = true;
                
                app.ErrordeaproximacinEditFieldLabel.Enable = true;
                app.IteracionesmximasSpinnerLabel.Enable = true;
                app.ToleranciaEditFieldLabel.Enable = true;
            else
                app.NSErrorEditField.Enable = false;
                app.NSMaxIterationsSpinner.Enable = false;
                app.NSToleranceEditField.Enable = false;
                
                app.ErrordeaproximacinEditFieldLabel.Enable = false;
                app.IteracionesmximasSpinnerLabel.Enable = false;
                app.ToleranciaEditFieldLabel.Enable = false;
            end
        end

        % Button pushed function: NSSolveButton
        function NSSolveButtonPushed(app, event)
            clc, value = app.MethodDropDown.Value;
            app.LinearSystemsInstance.setMethod(value);
            
            app.LoggerTextArea.Value = "Resolviendo el sistema, espere...";
            pause(1)
            
            mCoef = app.CoeffMatrixArea.Value;
            bVector = app.bVectorArea.Value;
            tolerance = app.NSToleranceEditField.Value;
            iterMax = app.NSMaxIterationsSpinner.Value;
            
            try
                app.LinearSystemsInstance.setParams(mCoef,bVector,tolerance,iterMax);
                app.AmountEquationsEditField.Value = app.LinearSystemsInstance.getDegree();
                [x,error,iter] = app.LinearSystemsInstance.getResults();
                
                app.xVectorArea.Value = x;
                app.NSErrorEditField.Value = error;
                
                value = app.MethodDropDown.Value;
                
                if strcmp(value,'Gauss-Seidel') || ...
                    strcmp(value,'Sobre-Relajación Sucesiva') || ...
                    strcmp(value,'Gradiente Conjugado')
                
                    if iter >= iterMax || error >= tolerance
                        str = sprintf("Sistema de ecuaciones NO solucionado, se hicieron %d iteraciones.",iter);
                    else
                        str = sprintf("Sistema de ecuaciones solucionado con %d iteraciones.",iter);
                    end
                    app.LoggerTextArea.Value = str;
                else
                    app.NSErrorEditField.Value = 0;
                    app.NSMaxIterationsSpinner.Value = 1;
                    app.LoggerTextArea.Value = "Sistema de ecuaciones solucionado.";
                end
            catch
                app.LoggerTextArea.Value = "Error al cargar la información.";
            end
        end

        % Value changed function: MethodNLEDropDown
        function MethodNLEDropDownValueChanged(app, event)
            clc, value = app.MethodNLEDropDown.Value;
            
            app.NonLinEqInstance.setMethod(value);
            app.LoggerTextArea.Value = sprintf('Método seleccionado >> %s\n',value);
            
            if strcmp(value,'Bisección') || strcmp(value,'Falsa Posición')
                app.FinishIntervalSpinner.Enable = true;
            else
                app.FinishIntervalSpinner.Enable = false;
            end
        end

        % Button pushed function: NLESolveButton
        function NLESolveButtonPushed(app, event)
            expression = str2sym(app.EquationEditField.Value);
            value = app.MethodNLEDropDown.Value;
            xa = app.InitIntervalSpinner.Value;
            xb = app.FinishIntervalSpinner.Value;
            
            if xa > xb
                aux = xa;
                xa = xb;
                xb = aux;
            end
            
            iterMax = app.NLEMaxIterationsSpinner.Value;
            tolerance = app.NLEToleranceEditField.Value;
            interval = [xa xb];
            
            app.LoggerTextArea.Value = "Graficando y resolviendo...";
            
            if strcmp(value,'Punto Fijo') || strcmp(value,'Newton-Raphson')
                space = [interval(1)-2 interval(1)+2];
            else
                dif = (interval(2) - interval(1));
                space = [interval(1)-dif interval(2)+dif];
            end
            
            hold(app.NLEUIAxes,'on')
            cla(app.NLEUIAxes)
            fplot(app.NLEUIAxes,expression,space,'b-','LineWidth',1)
            pause(1)
            
            app.NonLinEqInstance.setMethod(value);
            app.NonLinEqInstance.setParams(expression,interval,tolerance,iterMax);
            app.NonLinEqInstance.solve();
            
            [root,error,iter] = app.NonLinEqInstance.getResults();
            
            app.NLERootEditField.Value = root;
            app.NLEErrorEditField.Value = error;
            app.NLEIterEditField.Value = iter;
            
            if error < tolerance && iter < iterMax
                app.LoggerTextArea.Value = "Ecuación no lineal resuelta.";
                scatter(app.NLEUIAxes,root,subs(expression,root),'rx','LineWidth',2)
            else
                app.LoggerTextArea.Value = "No se pudo resolver la ecuación.";
            end
        end

        % Value changed function: DEMethodDropDown
        function DEMethodDropDownValueChanged(app, event)
            clc, value = app.DEMethodDropDown.Value;
            
            app.DiffEqInstance.setMethod(value);
            app.LoggerTextArea.Value = sprintf('Método seleccionado >> %s\n',value);
        end

        % Button pushed function: DESolveButton
        function DESolveButtonPushed(app, event)
            clc, value = app.DEMethodDropDown.Value;
            app.DiffEqInstance.setMethod(value);
            app.LoggerTextArea.Value = "Resolviendo la ecuación diferencial, espere...";
            pause(1)
            
            equation = replace(app.DEEquationEditField.Value,'ln','log');
            equation = str2sym(equation);
            xa = app.DEInitPosEditField.Value;
            xb = app.DEFinalPosEditField.Value;
            
            if xa > xb
                aux = xa;
                xa = xb;
                xb = aux;
            end
            
            interval = [xa xb];
            exact = app.DEExactValueEditField.Value;
            steps = app.DEStepsEditField.Value;
            initial = app.DEInitValueEditField.Value;
            
            try
                app.DiffEqInstance.setParams(equation,interval,initial,exact,steps);
                app.DiffEqInstance.solve();
                
                [rst,error,ratio] = app.DiffEqInstance.getResults();
                
                app.DEResultEditField.Value = rst;
                app.DEErrorEditField.Value = error;
                app.DEVarStepsEditField.Value = ratio;
                app.LoggerTextArea.Value = "Se encontró una solución.";
            catch
                app.LoggerTextArea.Value = "No se pudo resolver el problema.";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModeladoComputacionalMIA2022UIFigure = uifigure('Visible', 'off');
            app.ModeladoComputacionalMIA2022UIFigure.Name = 'Modelado Computacional ~ MIA 2022';
            app.TabGroup = uitabgroup(app.ModeladoComputacionalMIA2022UIFigure);
            app.LinearSystemsTab = uitab(app.TabGroup);
            app.LinearSystemsTab.Title = 'Sistemas lineales';
            app.MatrizdecoeficientesATextAreaLabel = uilabel(app.LinearSystemsTab);
            app.MatrizdecoeficientesATextAreaLabel.Text = 'Matriz de coeficientes [A]';
            app.CoeffMatrixArea = uitextarea(app.LinearSystemsTab);
            app.CoeffMatrixArea.Value = {'1 -3 2'; '2 5 0'; '0 -1 -2'};
            app.IncgnitasxTextAreaLabel = uilabel(app.LinearSystemsTab);
            app.IncgnitasxTextAreaLabel.Text = 'Incógnitas [x]';
            app.xVectorArea = uitextarea(app.LinearSystemsTab);
            app.xVectorArea.Editable = 'off';
            app.xVectorArea.Value = {'x1'; 'x2'; 'x3'};
            app.EqualLabel = uilabel(app.LinearSystemsTab);
            app.EqualLabel.Text = '=';
            app.VectorbLabel = uilabel(app.LinearSystemsTab);
            app.VectorbLabel.Text = 'Vector [b]';
            app.bVectorArea = uitextarea(app.LinearSystemsTab);
            app.bVectorArea.Value = {'12'; '10'; '6'};
            app.MtodoDropDownLabel = uilabel(app.LinearSystemsTab);
            app.MtodoDropDownLabel.Text = 'Método';
            app.MethodDropDown = uidropdown(app.LinearSystemsTab);
            app.MethodDropDown.Items = {'Gauss-Jordan', 'Matriz Adjunta', 'Eliminación Gaussiana', 'Thomas Llewellyn', 'Jacobi', 'Gauss-Seidel', 'Sobre-Relajación Sucesiva', 'Gradiente Conjugado'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Gauss-Jordan';
            app.NmerodeecuacionesEditFieldLabel = uilabel(app.LinearSystemsTab);
            app.NmerodeecuacionesEditFieldLabel.Text = 'Número de ecuaciones';
            app.AmountEquationsEditField = uieditfield(app.LinearSystemsTab, 'numeric');
            app.AmountEquationsEditField.Limits = [1 Inf];
            app.AmountEquationsEditField.ValueDisplayFormat = '%d';
            app.AmountEquationsEditField.Value = 3;
            app.ToleranciaEditFieldLabel = uilabel(app.LinearSystemsTab);
            app.ToleranciaEditFieldLabel.Enable = 'off';
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.NSToleranceEditField = uieditfield(app.LinearSystemsTab, 'numeric');
            app.NSToleranceEditField.Limits = [0 Inf];
            app.NSToleranceEditField.Enable = 'off';
            app.NSToleranceEditField.Value = 0.001;
            app.ErrordeaproximacinEditFieldLabel = uilabel(app.LinearSystemsTab);
            app.ErrordeaproximacinEditFieldLabel.Enable = 'off';
            app.ErrordeaproximacinEditFieldLabel.Text = 'Error de aproximación';
            app.NSErrorEditField = uieditfield(app.LinearSystemsTab, 'numeric');
            app.NSErrorEditField.Editable = 'off';
            app.NSErrorEditField.Enable = 'off';
            app.NSSolveButton = uibutton(app.LinearSystemsTab, 'push');
            app.NSSolveButton.ButtonPushedFcn = createCallbackFcn(app, @NSSolveButtonPushed, true);
            app.NSSolveButton.Text = 'Resolver';
            app.IteracionesmximasSpinnerLabel = uilabel(app.LinearSystemsTab);
            app.IteracionesmximasSpinnerLabel.Enable = 'off';
            app.IteracionesmximasSpinnerLabel.Text = 'Iteraciones máximas';
            app.NSMaxIterationsSpinner = uispinner(app.LinearSystemsTab);
            app.NSMaxIterationsSpinner.Limits = [1 Inf];
            app.NSMaxIterationsSpinner.Enable = 'off';
            app.NSMaxIterationsSpinner.Value = 1;
            app.NonLinearEquationsTab = uitab(app.TabGroup);
            app.NonLinearEquationsTab.Title = 'Ecuaciones no lineales';
            app.NLEUIAxes = uiaxes(app.NonLinearEquationsTab);
            title(app.NLEUIAxes, '')
            xlabel(app.NLEUIAxes, 'X')
            ylabel(app.NLEUIAxes, 'Y')
            app.EcuacinEditFieldLabel = uilabel(app.NonLinearEquationsTab);
            app.EcuacinEditFieldLabel.Text = 'Ecuación';
            app.EquationEditField = uieditfield(app.NonLinearEquationsTab, 'text');
            app.EquationEditField.Value = 'cos(x)-x';
            app.PosicinaLabel = uilabel(app.NonLinearEquationsTab);
            app.PosicinaLabel.Text = 'Posición (a)';
            app.InitIntervalSpinner = uispinner(app.NonLinearEquationsTab);
            app.InitIntervalSpinner.Step = 0.01;
            app.InitIntervalSpinner.ValueDisplayFormat = '%11.4f';
            app.MtodoDropDownLabel_2 = uilabel(app.NonLinearEquationsTab);
            app.MtodoDropDownLabel_2.Text = 'Método';
            app.MethodNLEDropDown = uidropdown(app.NonLinearEquationsTab);
            app.MethodNLEDropDown.Items = {'Bisección', 'Falsa Posición', 'Punto Fijo', 'Newthon-Raphson', 'Newthon-Raphson Modificado', 'Secante'};
            app.MethodNLEDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodNLEDropDownValueChanged, true);
            app.MethodNLEDropDown.Value = 'Bisección';
            app.PosicionbLabel = uilabel(app.NonLinearEquationsTab);
            app.PosicionbLabel.Text = 'Posicion (b)';
            app.FinishIntervalSpinner = uispinner(app.NonLinearEquationsTab);
            app.FinishIntervalSpinner.Step = 0.01;
            app.FinishIntervalSpinner.ValueDisplayFormat = '%11.4f';
            app.FinishIntervalSpinner.Value = 1;
            app.ToleranciaEditFieldLabel_2 = uilabel(app.NonLinearEquationsTab);
            app.ToleranciaEditFieldLabel_2.Text = 'Tolerancia';
            app.NLEToleranceEditField = uieditfield(app.NonLinearEquationsTab, 'numeric');
            app.NLEToleranceEditField.Limits = [0 Inf];
            app.NLEToleranceEditField.ValueDisplayFormat = '%11.7f';
            app.NLEToleranceEditField.Value = 0.001;
            app.ErrordeaproximacinEditFieldLabel_2 = uilabel(app.NonLinearEquationsTab);
            app.ErrordeaproximacinEditFieldLabel_2.Text = 'Error de aproximación';
            app.NLEErrorEditField = uieditfield(app.NonLinearEquationsTab, 'numeric');
            app.NLEErrorEditField.Editable = 'off';
            app.IteracionesmximasSpinnerLabel_2 = uilabel(app.NonLinearEquationsTab);
            app.IteracionesmximasSpinnerLabel_2.Text = 'Iteraciones máximas';
            app.NLEMaxIterationsSpinner = uispinner(app.NonLinearEquationsTab);
            app.NLEMaxIterationsSpinner.Limits = [1 Inf];
            app.NLEMaxIterationsSpinner.Value = 10;
            app.IteracionesEditFieldLabel = uilabel(app.NonLinearEquationsTab);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.NLEIterEditField = uieditfield(app.NonLinearEquationsTab, 'numeric');
            app.NLEIterEditField.Editable = 'off';
            app.RazencontradaEditFieldLabel = uilabel(app.NonLinearEquationsTab);
            app.RazencontradaEditFieldLabel.Text = 'Raíz encontrada';
            app.NLERootEditField = uieditfield(app.NonLinearEquationsTab, 'numeric');
            app.NLERootEditField.Editable = 'off';
            app.NLESolveButton = uibutton(app.NonLinearEquationsTab, 'push');
            app.NLESolveButton.ButtonPushedFcn = createCallbackFcn(app, @NLESolveButtonPushed, true);
            app.NLESolveButton.Text = 'Resolver';
            app.DiffEquationTab = uitab(app.TabGroup);
            app.DiffEquationTab.Title = 'Ecuaciones Diferenciales';
            app.EcuacinEditFieldLabel_2 = uilabel(app.DiffEquationTab);
            app.EcuacinEditFieldLabel_2.Text = 'Ecuación';
            app.DEEquationEditField = uieditfield(app.DiffEquationTab, 'text');
            app.DEEquationEditField.Value = 'sin(x)-log(y)';
            app.MtodoDropDownLabel_3 = uilabel(app.DiffEquationTab);
            app.MtodoDropDownLabel_3.Text = 'Método';
            app.DEMethodDropDown = uidropdown(app.DiffEquationTab);
            app.DEMethodDropDown.Items = {'Euler', 'Euler Mejorado', 'Range-Kutta 4to Orden'};
            app.DEMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DEMethodDropDownValueChanged, true);
            app.DEMethodDropDown.Value = 'Euler';
            app.Posicininicialx0EditFieldLabel = uilabel(app.DiffEquationTab);
            app.Posicininicialx0EditFieldLabel.Text = 'Posición inicial, x0';
            app.DEInitPosEditField = uieditfield(app.DiffEquationTab, 'numeric');
            app.DEInitPosEditField.ValueDisplayFormat = '%11.5f';
            app.DEInitPosEditField.Value = 0.13;
            app.Valorinicialy0EditFieldLabel = uilabel(app.DiffEquationTab);
            app.Valorinicialy0EditFieldLabel.Text = 'Valor inicial, y0';
            app.DEInitValueEditField = uieditfield(app.DiffEquationTab, 'numeric');
            app.DEInitValueEditField.ValueDisplayFormat = '%11.5f';
            app.DEInitValueEditField.Value = 0.32;
            app.PosicinfinalxfEditFieldLabel = uilabel(app.DiffEquationTab);
            app.PosicinfinalxfEditFieldLabel.Text = 'Posición final, xf';
            app.DEFinalPosEditField = uieditfield(app.DiffEquationTab, 'numeric');
            app.DEFinalPosEditField.ValueDisplayFormat = '%11.5f';
            app.DEFinalPosEditField.Value = 0.14;
            app.ValorexactoyEditFieldLabel = uilabel(app.DiffEquationTab);
            app.ValorexactoyEditFieldLabel.Text = 'Valor exacto, y';
            app.DEExactValueEditField = uieditfield(app.DiffEquationTab, 'numeric');
            app.DEExactValueEditField.ValueDisplayFormat = '%11.5f';
            app.DEExactValueEditField.Value = 0.3325459;
            app.CantidaddepasosEditFieldLabel = uilabel(app.DiffEquationTab);
            app.CantidaddepasosEditFieldLabel.Text = 'Cantidad de pasos';
            app.DEStepsEditField = uieditfield(app.DiffEquationTab, 'numeric');
            app.DEStepsEditField.Limits = [0 Inf];
            app.DEStepsEditField.Value = 4;
            app.VariacindepasosEditFieldLabel = uilabel(app.DiffEquationTab);
            app.VariacindepasosEditFieldLabel.Text = 'Variación de pasos';
            app.DEVarStepsEditField = uieditfield(app.DiffEquationTab, 'numeric');
            app.DEVarStepsEditField.Limits = [0 Inf];
            app.DEVarStepsEditField.ValueDisplayFormat = '%11.5f';
            app.DEVarStepsEditField.Editable = 'off';
            app.ErrorLabel = uilabel(app.DiffEquationTab);
            app.ErrorLabel.Text = 'Error (%)';
            app.DEErrorEditField = uieditfield(app.DiffEquationTab, 'numeric');
            app.DEErrorEditField.ValueDisplayFormat = '%11.8f';
            app.DEErrorEditField.Editable = 'off';
            app.ValoraproximadoEditFieldLabel = uilabel(app.DiffEquationTab);
            app.ValoraproximadoEditFieldLabel.Text = 'Valor aproximado';
            app.DEResultEditField = uieditfield(app.DiffEquationTab, 'numeric');
            app.DEResultEditField.ValueDisplayFormat = '%11.8f';
            app.DEResultEditField.Editable = 'off';
            app.DESolveButton = uibutton(app.DiffEquationTab, 'push');
            app.DESolveButton.ButtonPushedFcn = createCallbackFcn(app, @DESolveButtonPushed, true);
            app.DESolveButton.Text = 'Resolver';
            app.LoggerTextArea = uitextarea(app.ModeladoComputacionalMIA2022UIFigure);
            app.LoggerTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.ModeladoComputacionalMIA2022UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculadora

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModeladoComputacionalMIA2022UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModeladoComputacionalMIA2022UIFigure)
        end
    end
end